classdef ExploreDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        LoadDatasetPushTool      matlab.ui.container.toolbar.PushTool
        DeleteDatasetPushTool    matlab.ui.container.toolbar.PushTool
        GridLayout               matlab.ui.container.GridLayout
        PanelGeneralInformation  matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        LabelDSName              matlab.ui.control.Label
        TextAreaDSDescription    matlab.ui.control.TextArea
        DescriptionLabel         matlab.ui.control.Label
        PanelActions             matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        TreeActions              matlab.ui.container.Tree
        NodeAction1              matlab.ui.container.TreeNode
        Node2_3                  matlab.ui.container.TreeNode
        Node3_3                  matlab.ui.container.TreeNode
        Node4_3                  matlab.ui.container.TreeNode
        NodeAction2              matlab.ui.container.TreeNode
        Node2_4                  matlab.ui.container.TreeNode
        Node3_4                  matlab.ui.container.TreeNode
        Node4_4                  matlab.ui.container.TreeNode
        PanelMain                matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        PanelViewer              matlab.ui.container.Panel
        PanelControls            matlab.ui.container.Panel
        CloseButton              matlab.ui.control.Button
        Panel3                   matlab.ui.container.Panel
        PanelDatasets            matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        TreeDatasets             matlab.ui.container.Tree
        Node                     matlab.ui.container.TreeNode
        Node2                    matlab.ui.container.TreeNode
        Node3                    matlab.ui.container.TreeNode
        Node4                    matlab.ui.container.TreeNode
        Node_2                   matlab.ui.container.TreeNode
        Node2_2                  matlab.ui.container.TreeNode
        Node3_2                  matlab.ui.container.TreeNode
        Node4_2                  matlab.ui.container.TreeNode
    end


    properties (Access = public)
        Datasets; % Description
        BCVdir;
        Datasets_file;
        bcv_properties;
    end

    properties (Access = private)
        currentDS; % Description
        currentPt;
        currentAxes;
        colorMap; % Description
        UIAxes; % Description
    end

    methods (Access = private)

        function AnalysisReport(app,event,action,clasif)
            d = uiprogressdlg(app.UIFigure,'Title','Generating Pariticipant report');
             nodeLevel = action.InteractionInformation.Level;
             switch nodeLevel
                 case 1
                     dataset = action.ContextObject.NodeData;
                     participants = action.ContextObject.Children;
                     for i=1:length(participants)
                         nodeData = participants(i).NodeData;
                         userData = participants(i).UserData;
                         result = report_process_interface(dataset,clasif,nodeData,userData);
                     end
                 case 2
                     nodeData = action.ContextObject.NodeData;
                     userData = action.ContextObject.UserData;
                     dataset = action.ContextObject.Parent.NodeData;
                     result = report_process_interface(dataset,clasif,nodeData,userData);
             end
            
            close(d);
        end

        function FillDatasetsTree(app)
            AllDatasets = jsondecode(fileread(app.Datasets_file));
            delete(app.TreeDatasets.Children);            
            for i=1:length(app.Datasets)
                app.currentDS = app.Datasets(i);
                child = uitreenode(app.TreeDatasets,'Text',app.currentDS.Name);
                child.NodeData = app.currentDS;
                child.Icon = 'guide/images/box-solid.png';
                if(~isfile(fullfile(app.currentDS.Path,'BC_VARETA.json')))
                    style = uistyle("FontColor",[0.8500 0.3250 0.0980]);
                    addStyle(app.TreeDatasets, style, 'node', child);
                    contexMenu = uicontextmenu(app.UIFigure);
                    menu = uimenu(contexMenu,'Text',strcat("Update location"), "MenuSelectedFcn",{@app.DatasetAction,'update_path'});
                    menu = uimenu(contexMenu,'Text',strcat("Delete"), "MenuSelectedFcn",{@app.DatasetAction,'delete'});
                    child.ContextMenu = contexMenu;
                    child.set("Text",strcat(app.currentDS.Name, " (Wrong location)"));
                    continue;
                end
                contexMenu = uicontextmenu(app.UIFigure);
                if(isequal(app.currentDS.general_params.analysis_level.value,'all') ...
                        || isequal(app.currentDS.general_params.analysis_level.value,'1') ...
                        || isequal(app.currentDS.general_params.analysis_level.value,'12'))
                    menu = uimenu(contexMenu,'Text',strcat("Sensor level report"), "MenuSelectedFcn",{@app.AnalysisReport,'sensor_level'});
                end
                if(isequal(app.currentDS.general_params.analysis_level.value,'all') ...
                        || isequal(app.currentDS.general_params.analysis_level.value,'12') ...
                        || isequal(app.currentDS.general_params.analysis_level.value,'23'))
                    menu = uimenu(contexMenu,'Text',strcat("Activation level report"), "MenuSelectedFcn",{@app.AnalysisReport,'activation_level'});
                end
                if(isequal(app.currentDS.general_params.analysis_level.value,'all'))
                    menu = uimenu(contexMenu,'Text',strcat("Connectivity level report"), "MenuSelectedFcn",{@app.AnalysisReport,'connectivity_level'});
                end
                menu = uimenu(contexMenu,'Text','Dataset report', "MenuSelectedFcn",{@app.AnalysisReport,'subject'},'Separator','on');
                child.ContextMenu = contexMenu;
                indexDS = find(contains({AllDatasets.UUID},app.currentDS.UUID),1);
                participants = AllDatasets(indexDS).Participants;
                if(isfile(fullfile(app.currentDS.Path,'Participants.json')))
                    Participants_Info  = jsondecode(fileread(fullfile(app.currentDS.Path,'Participants.json')));
                end
                for j=1:length(participants)
                    participant = participants(j);
                    subChild = uitreenode(child,'Text',participant.SubID);
                    subChild.Icon = 'guide/images/participant.png';
                    subChild.NodeData = jsondecode(fileread(fullfile(app.currentDS.Path,participant.SubID,participant.FileInfo)));
                    try
                        subChild.UserData = Participants_Info(find(ismember({Participants_Info.SubID},participant.SubID),1));
                    catch
                        subChild.UserData = struct;
                    end
                    subChild.UserData.Condition = app.currentDS.Task;
                    if(isequal(participant.Status,"Completed"))
                        mStyle = uistyle("FontColor","blue");
                    else
                        mStyle = uistyle("FontColor","red");
                    end
                    addStyle(app.TreeDatasets,mStyle,"node",subChild);
                    contexMenu = uicontextmenu(app.UIFigure);
                    if(isequal(participant.Status,"Completed"))
                        PartInfo = jsondecode(fileread(fullfile(app.currentDS.Path,participant.SubID,participant.FileInfo)));
                        processes = PartInfo.Processes;
                        for k=1:length(processes)
                            process = processes(k);
                            if(process.completed)
                                menu = uimenu(contexMenu,'Text',strcat(process.name," report"), "MenuSelectedFcn",{@app.AnalysisReport,process.name});
                            end
                        end
                        menu = uimenu(contexMenu,'Text','Subject report', "MenuSelectedFcn",{@app.AnalysisReport,'subject'},'Separator','on');
                        subChild.ContextMenu = contexMenu;
                    end
                end
                clear Participants_Info;
            end
            delete(app.TreeActions.Children);
        end

        function DatasetAction(app,event,action,clasif)
             d = uiprogressdlg(app.UIFigure,'Title','Updating Dataset Location');
             app.currentDS = action.ContextObject.NodeData;
             switch clasif
                 case 'delete'
                     opts.Interpreter = 'tex';
                     % Include the desired Default answer
                     opts.Default = 'Cancel';
                     % Use the TeX interpreter to format the question
                     quest = {strcat("Are you sure about delete the dataset:"), ...
                         app.currentDS.Name};
                     answer = questdlg(quest,'Delete dataset',...
                         'Delete','Cancel',opts);
                     % Handle response
                     switch answer
                         case 'Delete'
                             for i=1:length(app.Datasets)
                                 dt = app.Datasets(i);
                                 if(isequal(dt.UUID,app.currentDS.UUID))
                                     app.Datasets(i) = [];
                                     break;
                                 end
                             end
                             [icondata,iconcmap] = imread("guide/images/success.png");
                             msg = msgbox({'The Dataset was deleted successfully!!!'},'Success',"custom",icondata,iconcmap);
                             movegui(msg,"center");
                             waitfor(msg);
                             disp("-->> Dataset deleted");
                         case 'Cancel'
                             close(d);
                             return;
                         otherwise
                     end
                 case 'update_path'
                     homedir = char(java.lang.System.getProperty('user.home'));
                     folder = uigetdir(homedir,"Select the Dataset new location");
                     if(isequal(folder,0))
                         close(d);
                         return;
                     end
                     dataset_file = fullfile(folder,'BC_VARETA.json');
                     if(isfile(dataset_file))
                         DT_info = jsondecode(fileread(dataset_file));
                         if(isequal(DT_info.UUID,app.currentDS.UUID))
                             app.currentDS.Path = folder;
                             [icondata,iconcmap] = imread("guide/images/success.png");
                             msg = msgbox({'The Datasets was updated successfully!!!'},'Success',"custom",icondata,iconcmap);
                             movegui(msg,"center");
                             waitfor(msg);
                             disp("-->> Datasets location updated");
                         else
                             [icondata,iconcmap] = imread("guide/images/warning.png");
                             msg = msgbox({'The selectect folder do not correspond with the current dataset'},'Warning',"custom",icondata,iconcmap);
                             movegui(msg,"center");
                             waitfor(msg);
                             close(d);
                             return;
                         end
                     end
                     if(~isfile(dataset_file))
                         [icondata,iconcmap] = imread("guide/images/warning.png");
                         msg = msgbox({'The selectect folder do not contain a correct structure'},'Warning',"custom",icondata,iconcmap);
                         movegui(msg,"center");
                         waitfor(msg);
                     end
             end
             saveJSON(app.Datasets, app.Datasets_file);
             app.FillDatasetsTree();
             close(d);
             figure(app.UIFigure);             
        end
        
        function SMEEGData(app, MEEG,iniTime)
            
            app.UIAxes = uiaxes(app.PanelViewer);
            pos = app.PanelViewer.Position;
            app.UIAxes.Position = [5 5 pos(3)-10 pos(4)-10];
            data = MEEG.data;
            time = MEEG.times;
            srate = MEEG.srate;
            chanlocs = MEEG.chanlocs;
            nbchan = MEEG.nbchan;
            pnts = MEEG.pnts;
            winlength	= 5; 	 % Number of seconds of EEG displayed

            %%
            %% getting spacing
            %%
            maxindex = min(1000, pnts);
            stds = std(data(:,1:maxindex),[],2);
            stds = sort(stds);
            if length(stds) > 2
                stds = mean(stds(2:end-1));
            else
                stds = mean(stds);
            end
            spacing = stds*3;
            if spacing > 10
                spacing = round(spacing);
            end
            if spacing  == 0 || isnan(spacing)
                spacing = 1; % default
            end       
            %%
            %% Plotting data
            %%
            lowlim = round((time+iniTime)*srate+1);
            highlim = round(min((time+iniTime+winlength)*srate+2,pnts));
            plot(app.UIAxes,bsxfun(@plus, data(end:-1:1,lowlim:highlim), spacing*(1:nbchan)')', ...
                'color', 'b', 'clipping','on');
            set(app.UIAxes, 'Xlim',[1 winlength*srate],...
                'XTick',1+iniTime:srate:(iniTime+winlength)*srate+1);
            set(app.UIAxes, 'XTickLabel', num2str((time+iniTime:1:time+iniTime+winlength)'));

            app.UIAxes.XLabel.String = 'Time (seg)';
            app.UIAxes.YLabel.String = 'Labels';
            set(app.UIAxes,'Ylim',[0 (nbchan+1)*spacing]);
            set(app.UIAxes,'YTick',0:spacing:nbchan*spacing);
            set(app.UIAxes,'TickLength',[.005 .005]);

            YLabels = {chanlocs.labels}';
            YLabels = strvcat(YLabels);
            YLabels = flipud(char(YLabels,' '));
            set(app.UIAxes,'YTickLabel',YLabels)
        end
        
        function SensorCrossSpectrum(app)    
            app.UIAxes = uiaxes(app.PanelViewer);             
            pos = app.PanelViewer.Position;
            app.UIAxes.Position = [5 5 pos(3)-10 pos(4)-10];
            frequencies  = app.currentDS.sensor_params.frequencies; 
            if(isfield(frequencies,'f_bin'))
                freqs = [frequencies.f_bin];
            else
                maxFreq = app.currentDS.sensor_params.max_freq.value;
                freqRes = app.currentDS.sensor_params.freq_resol.value;
                freqs = 0:freqRes:maxFreq;
            end
            spectrum = load(fullfile(app.currentDS.Path,app.currentPt.SubID,'Generals/Functional/Data_spectrum.mat'));
            PSD_log = 10*log10(abs(spectrum.PSD));
            plot(app.UIAxes,freqs,PSD_log);
            app.UIAxes.XLabel.String    = 'Freq. (Hz)';
            app.UIAxes.FontSize         = 14;
            app.UIAxes.XColor           = 'K';
            app.UIAxes.YLabel.String    = 'PSD (dB)';
            app.UIAxes.YColor           = 'K';
            app.UIAxes.Title.String     = 'Power Spectral Density';
        end

        function SourceCrossSpectrum(app)    
            app.UIAxes = uiaxes(app.PanelViewer);             
            pos = app.PanelViewer.Position;
            app.UIAxes.Position = [5 5 pos(3)-10 pos(4)-10];
            frequencies  = app.currentDS.sensor_params.frequencies; 
            if(isfield(frequencies,'f_bin'))
                freqs = [frequencies.f_bin];
            else
                maxFreq = app.currentDS.sensor_params.max_freq.value;
                freqRes = app.currentDS.sensor_params.freq_resol.value;
                freqs = 0:freqRes:maxFreq;
            end
            activations = app.currentPt.activation_level;  
            sourceActiv = [];
            for i=1:length(activations)
                activ_info = activations(i);
                activ = load(fullfile(app.currentDS.Path,app.currentPt.SubID,activ_info.Ref_path,activ_info.Name),'J');
                sourceActiv = [sourceActiv, activ.J];
            end            
            PSD_log = log(abs(sourceActiv));
            plot(app.UIAxes,freqs,PSD_log);
            app.UIAxes.XLabel.String    = 'Freq. (Hz)';
            app.UIAxes.FontSize         = 14;
            app.UIAxes.XColor           = 'K';
            app.UIAxes.YLabel.String    = 'PSD (dB)';
            app.UIAxes.YColor           = 'K';
            app.UIAxes.Title.String     = 'Power Spectral Density';
        end
        
        function SCortex(app, Cortex)            
            clf(app.PanelViewer);
            delete(app.PanelViewer.Children);
            template        = load('axes.mat');
            app.currentAxes = template.axes;
            % Selecting Hemisphere
            [Cortex, ~]     = app.split_hemisphere(Cortex);
            FaceColor =   app.PanelControls.Children(4).Value;
            if(app.PanelControls.Children(2).Value)
                FaceColor =   [0.6 0.6 0.6];
            end
            patch( app.currentAxes,...
                'Faces',            Cortex.Faces, ...
                'Vertices',         Cortex.Vertices,...
                'VertexNormals',    Cortex.VertNormals, ...
                'VertexNormalsMode', 'auto',...
                'FaceNormals',      [],...
                'FaceNormalsMode',  'auto',...
                'FaceVertexCData',  [], ...
                'FaceColor',        FaceColor, ...
                'FaceAlpha',        1, ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'LineWidth',         0.5,...
                'LineJoin',         'miter',...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'Tag',              'AnatSurface');
            if(app.PanelControls.Children(2).Value)
                sAtlas = Cortex.Atlas(Cortex.iAtlas);
                sScouts = sAtlas.Scouts;
                Vertices = Cortex.Vertices;
                Faces = Cortex.Faces;
                % Process each scout
                for i = 1:length(sScouts)
                    scoutColor = sScouts(i).Color;
                    % Get visible scouts vertices
                    iScoutVert = sScouts(i).Vertices;
                    patchVert = Vertices(iScoutVert,:);
                    scoutFaces = [];
                    countFace = 1;
                    vertMask = false(length(Vertices),1);
                    vertMask(iScoutVert) = true;
                    iFacesTmp = find(vertMask(Faces(:,1)));
                    iFacesTmp = iFacesTmp(vertMask(Faces(iFacesTmp,2)));
                    iFacesTmp = iFacesTmp(vertMask(Faces(iFacesTmp,3)));
                    patchFaces = Faces(iFacesTmp,:);
                    [~, patchFaces] = ismember(patchFaces, iScoutVert);
                    EdgeColor = 'none';
                    patchAlpha = 0.2;
                    patch(app.currentAxes,...
                        'Faces',            patchFaces, ...
                        'Vertices',         patchVert, ...
                        'FaceVertexCData',  scoutColor, ...
                        'FaceColor',        scoutColor, ...
                        'EdgeColor',        EdgeColor,...
                        'FaceAlpha',        1 - patchAlpha, ...
                        'BackFaceLighting', 'lit', ...
                        'Tag',              'ScoutPatch', ...
                        'AmbientStrength',  0.5, ...
                        'DiffuseStrength',  0.5, ...
                        'SpecularStrength', 0.2, ...
                        'SpecularExponent', 1, ...
                        'SpecularColorReflectance', 0.5, ...
                        'FaceLighting',     'gouraud', ...
                        'EdgeLighting',     'gouraud');
                end
            else
                delete(findobj(app.currentAxes, 'type', 'Patch', 'tag', 'ScoutPatch'));
            end  

            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on');
            view(app.currentAxes,180,90);            
        end        
                
        function ChangeTimeMEEGData(app,MEEG,action)
            iniTime = str2num(app.PanelControls.Children(3).Value);            
            switch action
                case 1
                    iniTime = iniTime-5;     % << subtract one window length
                case 2
                    iniTime = iniTime-1;     % < subtract one second
                case 3
                    iniTime = iniTime+1;     % > add one second
                case 4
                    iniTime = iniTime+5;     % >> add one window length                    
            end   
            if(iniTime < 0)
                iniTime = 0;
            end
            if(iniTime+5 > round(MEEG.xmax))
                iniTime = round(MEEG.xmax) - 5;
            end
            app.PanelControls.Children(3).Value = string(iniTime);
           app.SMEEGData(MEEG,iniTime);
        end

        function SChannel(app,Shead,Cdata)
            delete(app.currentAxes);            
            template = load('axes.mat');
            app.currentAxes = template.axes;
            Channel     = Cdata.Channel;
            ChanLoc     = [Channel.Loc]';            
            patch(app.currentAxes, ...
                'Faces',Shead.Faces, ...
                'Vertices',Shead.Vertices, ...
                'FaceVertexCData',[], ...
                'FaceColor',[.6 .6 .6], ...
                'EdgeColor','none', ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'BackfaceLighting', 'lit', ...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'FaceAlpha',.99);
            ctColor         = [.9,.9,0]; % Yellow
            nVert           = 34;
            ElectrodeGrid = CreateGeometry3DElectrode_2(ChanLoc,'EEG',Shead, ctColor, nVert);
            patch(app.currentAxes,...
                'Faces',               ElectrodeGrid.Faces, ...
                'Vertices',            ElectrodeGrid.Vertices,...
                'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                'FaceColor',           'flat', ...
                'FaceAlpha',           'flat', ...
                'AlphaDataMapping',    'none', ...
                ElectrodeGrid.Options{:});
            colormap(gca,app.colorMap.cmap_a);
            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on');
            view(app.currentAxes,0,0);  
        end
        
        function SBEMSurface(app,Shead,Souter,Sinner,Cortex)
            clf(app.PanelViewer);
            delete(app.PanelViewer.Children);
            template = load('axes.mat');
            app.currentAxes = template.axes;
            isScalp = app.PanelControls.Children(4).Value;
            isOuter = app.PanelControls.Children(3).Value;
            isInner = app.PanelControls.Children(2).Value;
            isCortex = app.PanelControls.Children(1).Value;
            if(~isScalp && ~isOuter && ~isInner && ~isCortex )
                [app.PanelControls.Children(4).Value, isScalp, ...
                    app.PanelControls.Children(3).Value, isOuter, ...
                    app.PanelControls.Children(2).Value, isInner, ...
                    app.PanelControls.Children(1).Value, isCortex]  = deal(1);
            end
            if(isCortex)
                patch( app.currentAxes,...
                    'Faces',            Cortex.Faces, ...
                    'Vertices',         Cortex.Vertices,...
                    'VertexNormals',    Cortex.VertNormals, ...
                    'VertexNormalsMode', 'auto',...
                    'FaceNormals',      [],...
                    'FaceNormalsMode',  'auto',...
                    'FaceVertexCData',  [], ...
                    'FaceColor',        [255/255 204/255 204/255], ...
                    'FaceAlpha',        1, ...
                    'AlphaDataMapping', 'none', ...
                    'EdgeColor',        'none', ...
                    'EdgeAlpha',        1, ...
                    'LineWidth',         0.5,...
                    'LineJoin',         'miter',...
                    'AmbientStrength',  0.5, ...
                    'DiffuseStrength',  0.5, ...
                    'SpecularStrength', 0.2, ...
                    'SpecularExponent', 1, ...
                    'SpecularColorReflectance', 0.5, ...
                    'FaceLighting',     'gouraud', ...
                    'EdgeLighting',     'gouraud', ...
                    'Tag',              'AnatSurface');
            end
            if(isInner)
                patch( app.currentAxes,...
                    'Faces',            Sinner.Faces, ...
                    'Vertices',         Sinner.Vertices,...
                    'VertexNormals',    Sinner.VertNormals, ...
                    'VertexNormalsMode', 'auto',...
                    'FaceNormals',      [],...
                    'FaceNormalsMode',  'auto',...
                    'FaceVertexCData',  [],...
                    'FaceColor',        [0.7 0.7 0.7], ...
                    'FaceAlpha',        .30, ...
                    'AlphaDataMapping', 'none', ...
                    'EdgeColor',        'none', ...
                    'EdgeAlpha',        .30, ...
                    'LineWidth',         0.5,...
                    'LineJoin',         'miter',...
                    'AmbientStrength',  0.5, ...
                    'DiffuseStrength',  0.5, ...
                    'SpecularStrength', 0.2, ...
                    'SpecularExponent', 1, ...
                    'SpecularColorReflectance', 0.5, ...
                    'FaceLighting',     'gouraud', ...
                    'EdgeLighting',     'gouraud', ...
                    'Tag',              'AnatSurface');
            end
            if(isOuter)
                patch( app.currentAxes,...
                    'Faces',            Souter.Faces, ...
                    'Vertices',         Souter.Vertices,...
                    'VertexNormals',    Souter.VertNormals, ...
                    'VertexNormalsMode', 'auto',...
                    'FaceNormals',      [],...
                    'FaceNormalsMode',  'auto',...
                    'FaceVertexCData',  [], ...
                    'FaceColor',        [0.7 0.7 0.7], ...
                    'FaceAlpha',        .20, ...
                    'AlphaDataMapping', 'none', ...
                    'EdgeColor',        'none', ...
                    'EdgeAlpha',        .20, ...
                    'LineWidth',         0.5,...
                    'LineJoin',         'miter',...
                    'AmbientStrength',  0.5, ...
                    'DiffuseStrength',  0.5, ...
                    'SpecularStrength', 0.2, ...
                    'SpecularExponent', 1, ...
                    'SpecularColorReflectance', 0.5, ...
                    'FaceLighting',     'gouraud', ...
                    'EdgeLighting',     'gouraud', ...
                    'Tag',              'AnatSurface');
            end
            if(isScalp)
                patch( app.currentAxes,...
                    'Faces',            Shead.Faces, ...
                    'Vertices',         Shead.Vertices,...
                    'VertexNormals',    Shead.VertNormals, ...
                    'VertexNormalsMode', 'auto',...
                    'FaceNormals',      [],...
                    'FaceNormalsMode',  'auto',...
                    'FaceVertexCData',  [], ...
                    'FaceColor',        [0.7 0.7 0.7], ...
                    'FaceAlpha',        .10, ...
                    'AlphaDataMapping', 'none', ...
                    'EdgeColor',        'none', ...
                    'EdgeAlpha',        .10, ...
                    'LineWidth',         0.5,...
                    'LineJoin',         'miter',...
                    'AmbientStrength',  0.5, ...
                    'DiffuseStrength',  0.5, ...
                    'SpecularStrength', 0.2, ...
                    'SpecularExponent', 1, ...
                    'SpecularColorReflectance', 0.5, ...
                    'FaceLighting',     'gouraud', ...
                    'EdgeLighting',     'gouraud', ...
                    'Tag',              'AnatSurface');
            end

            % % % Ploting channles
            % Cdata = load(fullfile(app.currentDS.Path, ...
            %     app.currentPt.SubID, ...
            %     app.currentPt.common(5).Ref_path, ...
            %     app.currentPt.common(5).Name));
            % Channel                 = Cdata.Channel;
            % ChanLoc         = [Channel.Loc]';
            % ctColor         = [.9,.9,0]; % Yellow
            % nVert           = 34;
            % ElectrodeGrid   = CreateGeometry3DElectrode_2(ChanLoc,'EEG',Shead, ctColor, nVert);
            % % ElectrodeGrid   = CreateGeometry3DElectrode(Channel, ChanLoc,ctColor, sizeScale);
            % patch(app.currentAxes,...
            %     'Faces',               ElectrodeGrid.Faces, ...
            %     'Vertices',            ElectrodeGrid.Vertices,...
            %     'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
            %     'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
            %     'FaceColor',           'flat', ...
            %     'FaceAlpha',           'flat', ...
            %     'AlphaDataMapping',    'none', ...
            %     ElectrodeGrid.Options{:});

            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on');
            view(app.currentAxes,0,0);

        end
        function SHeadmodel(app,Headmodel,Cdata,Shead,Cortex)
            clf(app.PanelViewer);
            delete(app.PanelViewer.Children);
            delete(app.currentAxes);             
            template = load('axes.mat');
            app.currentAxes = template.axes;
            elecName = app.PanelControls.Children(2).Value;
            isScalp  = app.PanelControls.Children(1).Value;
            
            Channel     = Cdata.Channel;
            elecIndex   = find(ismember({Channel.Name},elecName),1);
            %% initial computations
            %% plot Lead Field
            [Ne,Nv]     = size(Headmodel.Gain);
            Nv          = Nv/3;
            XYZ         = Cortex.Vertices;
            Kn          = reshape(Headmodel.Gain,Ne,3,Nv);
            Kn          = permute(Kn,[1,3,2]);
            Kq          = permute(Kn, [1 3 2]);
            Kq          = permute(Kq, [3 2 1]);
            Kqm         = sqrt(dot(Kq,Kq,2));
            KqmC        = Kqm(:,:,elecIndex);            
            quiver3(app.currentAxes,XYZ(:,1),XYZ(:,2),XYZ(:,3), ...
                Kq(:,1,elecIndex),Kq(:,2,elecIndex),Kq(:,3,elecIndex), ...
                'LineWidth',2,'Color',[0 0 1],'AutoScaleFactor',5);
            hold on;
            
            %% define electrode to show and Marker size
            patch( app.currentAxes,...
                'Faces',            Cortex.Faces, ...
                'Vertices',         Cortex.Vertices,...
                'VertexNormals',    Cortex.VertNormals, ...
                'VertexNormalsMode', 'auto',...
                'FaceNormals',      [],...
                'FaceNormalsMode',  'auto',...
                'FaceVertexCData',  KqmC, ...
                'FaceColor',        'inter', ...
                'FaceAlpha',        1, ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'LineWidth',         0.5,...
                'LineJoin',         'miter',...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'Tag',              'AnatSurface');  
            hold on;
            
            % plotting scalp
            if(isScalp)
                patch( app.currentAxes,...
                    'Faces',            Shead.Faces, ...
                    'Vertices',         Shead.Vertices,...
                    'VertexNormals',    Shead.VertNormals, ...
                    'VertexNormalsMode', 'auto',...
                    'FaceNormals',      [],...
                    'FaceNormalsMode',  'auto',...
                    'FaceVertexCData',  [], ...
                    'FaceColor',        [0.7 0.7 0.7], ...
                    'FaceAlpha',        .10, ...
                    'AlphaDataMapping', 'none', ...
                    'EdgeColor',        'none', ...
                    'EdgeAlpha',        .10, ...
                    'LineWidth',         0.5,...
                    'LineJoin',         'miter',...
                    'AmbientStrength',  0.5, ...
                    'DiffuseStrength',  0.5, ...
                    'SpecularStrength', 0.2, ...
                    'SpecularExponent', 1, ...
                    'SpecularColorReflectance', 0.5, ...
                    'FaceLighting',     'gouraud', ...
                    'EdgeLighting',     'gouraud', ...
                    'Tag',              'AnatSurface');
                hold on;
            end

            % Ploting channles            
            ChanLoc         = [Channel.Loc]';             
            ctColor         = [.9,.9,0]; % Yellow            
            nVert = 34;
            ElectrodeGrid = CreateGeometry3DElectrode_2(ChanLoc,'EEG',Shead, ctColor, nVert);
            patch(app.currentAxes,...
                'Faces',               ElectrodeGrid.Faces, ...
                'Vertices',            ElectrodeGrid.Vertices,...
                'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                'FaceColor',           'flat', ...
                'FaceAlpha',           'flat', ...
                'AlphaDataMapping',    'none', ...
                ElectrodeGrid.Options{:});

            % Ploting selected channel
            SelectChannel         = Cdata.Channel(elecIndex);
            SelectChanLoc         = [SelectChannel.Loc]';  
            ctColor         = [1 0 0];
            ElectrodeGrid = CreateGeometry3DElectrode_2(SelectChanLoc,'EEG',Shead, ctColor, nVert);
            patch(app.currentAxes,...
                'Faces',               ElectrodeGrid.Faces, ...
                'Vertices',            ElectrodeGrid.Vertices,...
                'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                'FaceColor',           'flat', ...
                'FaceAlpha',           'flat', ...
                'AlphaDataMapping',    'none', ...
                ElectrodeGrid.Options{:});
            colormap(app.currentAxes,app.colorMap.cmap_a);
            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on');
            view(app.currentAxes,180,90);
            axis(app.currentAxes,'equal');
            axis(app.currentAxes,'off');
            
        end
        
        function SensorTopography(app,sensorData)            
            clf(app.PanelViewer)
            delete(app.currentAxes);  
            template = load('axes.mat');
            app.currentAxes = template.axes;
            set(app.currentAxes,"Parent",app.PanelViewer);
            Svv = sensorData.Svv;
            Cdata = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                app.currentPt.common(5).Ref_path, ...
                app.currentPt.common(5).Name));
            Channel                 = Cdata.Channel;
            elec_data               = [];
            elec_data.pos           = zeros(length(Channel),3);
            for ii = 1:length(Channel)
                elec_data.lbl{ii}   = Channel(ii).Name;
                loc                 = Channel(ii).Loc;
                elec_data.pos(ii,:) = mean(loc,2);
            end
            elec_data.label         = elec_data.lbl;
            elec_data.elecpos       = elec_data.pos;
            elec_data.unit          = 'mm';


            SvvDiag                 = diag(Svv);
            SvvDiag                 = abs(SvvDiag)/max(abs(SvvDiag(:)));
            cfg                     = [];
            topo                    = [];

            % if(isequal(subject.modality,'MEG'))
            %     %% MEG topography
            %     if(isequal(properties.sensor_params.fieldtrip.layout.value,'4D248_helmet.mat'))
            %         cfg.layout          = properties.sensor_params.fieldtrip.layout.value;
            %         cfg.channel         = 'meg';
            %         cfg.markers         = '.';
            %         cfg.markersymbol    = '.';
            %         cfg.colormap        = cmap_a;
            %         cfg.markersize      = 3;
            %         cfg.markercolor     = [1 1 1];
            %     elseif(isequal(properties.sensor_params.fieldtrip.layout.value,'another'))
            % 
            %     end
            %     topo.sens           = elec_data;
            %     topo.tra            = elec_data.pos;
            %     topo.coilpos        = elec_data.pos;
            %     topo.label          = elec_data.lbl';
            %     topo.dimord         = 'chan_freq';
            %     topo.freq           = 1;
            %     topo.powspctrm      = SvvDiag;
            % else
            %% EEG topography
            cfg.marker          = 'on';
            cfg.layout          = 'EEG1005.lay';
            cfg.channel         = elec_data.label';
            cfg.markersymbol    = '.';
            cfg.colormap        = app.colorMap.cmap_a;
            cfg.markersize      = 20;
            cfg.markercolor     = 'g';
            cfg.comment         = 'no';
            cfg.interactive     = 'no';
            cfg.colorbar        = 'East';
            % Topo
            topo.elec           = elec_data;
            topo.label          = elec_data.lbl;
            topo.dimord         = 'chan_freq';
            topo.freq           = 1;
            topo.powspctrm      = SvvDiag;
            % end            
            ft_topoplotTFR(cfg,topo);  
            view(app.currentAxes,0,0);
            view(app.currentAxes,0,90);   
        end
        
        function SensorTopography3D(app,sensorData)
            clf(app.PanelViewer)
            delete(app.currentAxes); 
            template = load('axes.mat');
            app.currentAxes = template.axes;
            Svv         = sensorData.Svv;
            Shead       = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                app.currentPt.common(1).Ref_path, ...
                app.currentPt.common(1).Name));
            Cdata       = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                app.currentPt.common(5).Ref_path, ...
                app.currentPt.common(5).Name));
            Channel     = Cdata.Channel;            
            ChanLoc     = [Channel.Loc]';

            % if(isequal(subject.modality,'MEG'))
            %     Loc = squeeze(mean(reshape(Loc,3,4,Nelec),2));
            % end

            %% Getting Interpolation
            Nh = length(Shead.Vertices);
            hsGain = Shead.hsGain;
            hSvvDiag = zeros(Nh,1);
            for h = 1:Nh
                hSvvDiag(h) = hsGain(h,:)*Svv*hsGain(h,:)';
            end
            hSvvDiag        = abs(hSvvDiag)/max(abs(hSvvDiag));
            % hSvvDiag        = log(hSvvDiag);
            % hSvvDiag        = hSvvDiag - min(hSvvDiag);
            % hSvvDiag        = hSvvDiag/max(hSvvDiag);
            
            patch(app.currentAxes, ...
                'Faces',Shead.Faces, ...
                'Vertices',Shead.Vertices, ...
                'FaceVertexCData',hSvvDiag, ...
                'FaceColor','inter', ...
                'EdgeColor','none', ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'BackfaceLighting', 'lit', ...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'FaceAlpha',.99); 
            caxis([min(hSvvDiag) max(hSvvDiag)]);
            % Plotting sensors
            ctColor         = [.9,.9,0]; % Yellow
            nVert           = 34;
            ElectrodeGrid = CreateGeometry3DElectrode_2(ChanLoc,'EEG',Shead, ctColor, nVert);       
            SvvDiag                 = diag(Svv);
            SvvDiag                 = abs(SvvDiag)/max(abs(SvvDiag(:))); 
            % SvvDiag                 = log(SvvDiag);
            % SvvDiag                 = SvvDiag - min(SvvDiag);
            % SvvDiag                 = SvvDiag/max(SvvDiag);
            colors                  = repelem(SvvDiag,34);         
            ElectrodeGrid.FaceVertexCData = [];
            ElectrodeGrid.FaceVertexCData = colors;  
            patch(app.currentAxes,...
                'Faces',               ElectrodeGrid.Faces, ...
                'Vertices',            ElectrodeGrid.Vertices,...
                'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                'FaceColor',           'inter', ...
                'FaceAlpha',           'flat', ...
                'AlphaDataMapping',    'none', ...
                ElectrodeGrid.Options{:});
            colormap(app.currentAxes,app.colorMap.cmap_a);   
            caxis([min(SvvDiag) max(SvvDiag)]);           
            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on');
            view(app.currentAxes,0,0);
            colorbar(app.currentAxes,'eastoutside');
        end
        
        function SICM(app,sensorData)
            clf(app.PanelViewer)
            delete(app.currentAxes);     
            template        = load('axes.mat');
            app.currentAxes = template.axes;
            Cdata           = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                app.currentPt.common(5).Ref_path, ...
                app.currentPt.common(5).Name));
            Channel         = Cdata.Channel;
            Nelec           = length(Channel);
            Svv             = sensorData.Svv;
            Svv_inv         = sqrtm(Svv*Svv+4*eye(Nelec))-Svv;
            temp_diag       = diag(diag(abs(Svv_inv)));
            temp_ndiag      = abs(Svv_inv)-temp_diag;
            temp_ndiag      = temp_ndiag/max(temp_ndiag(:));
            temp_diag       = diag(abs(diag(Svv)));
            temp_diag       = temp_diag/max(temp_diag(:));
            temp_diag       = diag(diag(temp_diag)+1);
            temp_comp       = temp_diag+temp_ndiag;
            imagesc(app.currentAxes,1:1:length(Nelec),1:1:Nelec,temp_comp); 
            set(app.currentAxes,'Color','w','XColor','k','YColor','k','ZColor','k',...
                'XTick',1:Nelec,'YTick',1:Nelec,...
                'XTickLabel',{Channel.Name},'XTickLabelRotation',90,...
                'YTickLabel',{Channel.Name},'YTickLabelRotation',0);
            xlabel(app.currentAxes,'electrodes','Color','k','FontSize',14,'FontWeight','bold');
            ylabel(app.currentAxes,'electrodes','Color','k','FontSize',14,'FontWeight','bold');
            colormap(app.currentAxes,app.colorMap.cmap_a);            
            axis(app.currentAxes,'square');   
            set(app.currentAxes,"Parent",app.PanelViewer);
            colorbar(app.currentAxes,'eastoutside');
            colorbar(app.currentAxes,'southoutside');
        end      
                
        function SourceTopography(app,selectedNode,nodeData)
            clf(app.PanelViewer);
            delete(app.PanelViewer.Children);
            delete(app.currentAxes);  
            template                = load('axes.mat');
            app.currentAxes         = template.axes;
            activationData          = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                nodeData.Ref_path, ...
                nodeData.Name));
            Cortex                  = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                app.currentPt.common(4).Ref_path, ...
                app.currentPt.common(4).Name));  
            % Selecting Hemisphere
            [Cortex, iHideVert]     = app.split_hemisphere(Cortex);
            J                       = activationData.J;
            J(iHideVert)            = [];
            sources_iv              = sqrt(abs(J));
            sources_iv              = sources_iv/max(sources_iv(:));            
            smoothValue             = 0.66;
            SurfSmoothIterations    = 10;
            Vertices                = tess_smooth(Cortex.Vertices, smoothValue, SurfSmoothIterations, Cortex.VertConn, 1);
            
            patch(app.currentAxes, ...
                'Faces',Cortex.Faces, ...
                'Vertices',Vertices, ...
                'FaceVertexCData',Cortex.SulciMap*0.06+log(1+sources_iv), ...
                'FaceColor','interp', ...
                'EdgeColor','none', ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'BackfaceLighting', 'lit', ...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'FaceAlpha',1);
            set(app.currentAxes,'xcolor','w','ycolor','w','zcolor','w');            
            view(app.currentAxes,0,0);            
            colormap(app.currentAxes,app.colorMap.cmap_a);            
            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on'); 
        end        
        
        function SourceTopographyStat(app,selectedNode,nodeData)
            clf(app.PanelViewer)
            delete(app.currentAxes);
            app.PanelViewer.AutoResizeChildren = 'off';
            activationData      = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                nodeData.Ref_path, ...
                nodeData.Name));            
            if(size(activationData.stat,2)==1)
                stat            = activationData.stat(:,1);
                h               = histogram(stat,1000,'Normalization','pdf','Parent',app.PanelViewer);
                bins            = h.BinEdges;
                pdf             = (1/(sqrt(2*pi)))*(bins.^(-1/2)).*exp(-bins/2);
                hold on
                plot(bins,pdf,'LineWidth',2,'Color','r')
                legend('Empirical','Chi2');
            else            
                ax1             = subplot(1,2,1,'Parent',app.PanelViewer);
                stat_giri       = activationData.stat(:,1);
                h               = histogram(stat_giri,1000,'Normalization','pdf','Parent',app.PanelViewer);
                bins            = h.BinEdges;
                pdf             = (1/(sqrt(2*pi)))*(bins.^(-1/2)).*exp(-bins/2);
                hold on
                plot(ax1,bins,pdf,'LineWidth',2,'Color','r')
                legend('Empirical','Chi2');
                xlabel('amplitude');
                ylabel('probabilities');
                title('Stat-giri','Color','k','FontSize',16);
                ax2             = subplot(1,2,2,'Parent',app.PanelViewer);
                stat_sulc       = activationData.stat(:,2);
                h               = histogram(stat_sulc,1000,'Normalization','pdf','Parent',app.PanelViewer);
                bins            = h.BinEdges;
                pdf             = (1/(sqrt(2*pi)))*(bins.^(-1/2)).*exp(-bins/2);
                hold on
                plot(ax2,bins,pdf,'LineWidth',2,'Color','r')
                legend('Empirical','Chi2');
                xlabel('amplitude');
                ylabel('probabilities');     
                title('Stat-sulci','Color','k','FontSize',16);
            end
            app.PanelViewer.AutoResizeChildren = 'on';            
        end
        
        function SourceTopographySparse(app,selectedNode,nodeData,sparse)
            clf(app.PanelViewer);
            delete(app.PanelViewer.Children);
            delete(app.currentAxes);
            template                = load('axes.mat');
            app.currentAxes         = template.axes;
            activationData          = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                nodeData.Ref_path, ...
                nodeData.Name));
            stat                    = activationData.stat;
            Cortex                  = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                app.currentPt.common(4).Ref_path, ...
                app.currentPt.common(4).Name));
            if(sparse)
                Jsp                 = activationData.Jsp;
            else
                J = activationData.J;
                if(isequal(size(stat,2),1))
                    indms           = find(stat > app.PanelControls.Children(3).Value);
                    Jsp(indms)      = J(indms);
                else
                    stat_giri       = stat(:,1); 
                    stat_sulc       = stat(:,2);                     
                    indms_giri      = find(stat_giri > app.PanelControls.Children(4).Value);
                    indms_sulc      = find(stat_sulc > app.PanelControls.Children(4).Value);
                    indms           = unique([indms_giri;indms_sulc]);
                    Jsp             = zeros(length(stat),1);
                    Jsp(indms)      = J(indms);
                end
            end
            sources_iv              = sqrt(abs(Jsp));
            sources_iv              = sources_iv/max(sources_iv(:));
            smoothValue             = 0.66;
            SurfSmoothIterations    = 10;
            Vertices                = tess_smooth(Cortex.Vertices, smoothValue, SurfSmoothIterations, Cortex.VertConn, 1);

            patch(app.currentAxes, ...
                'Faces',Cortex.Faces, ...
                'Vertices',Vertices, ...
                'FaceVertexCData',Cortex.SulciMap*0.06+log(1+sources_iv), ...
                'FaceColor','interp', ...
                'EdgeColor','none', ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'BackfaceLighting', 'lit', ...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'FaceAlpha',.99);
            set(app.currentAxes,'xcolor','w','ycolor','w','zcolor','w');
            view(app.currentAxes,0,0);
            colormap(app.currentAxes,app.colorMap.cmap_a);
            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on');
        end   

        function SourceConnnectivity(app,nodeData)
            clf(app.PanelViewer);
            delete(app.PanelViewer.Children);
            delete(app.currentAxes);  
            template                = load('axes.mat');
            app.currentAxes         = template.axes;
            connectivityData          = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                nodeData.Ref_path, ...
                nodeData.Name),'Thetajj');
            Thetajj = connectivityData.Thetajj;
            Cortex                  = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                app.currentPt.common(4).Ref_path, ...
                app.currentPt.common(4).Name));  
            Thetajj = abs(Thetajj(:,:));
            Atlas = Cortex.Atlas(Cortex.iAtlas);
            Scouts = Atlas.Scouts;

            % Selecting Hemisphere
            [Cortex, iHideVert]     = app.split_hemisphere(Cortex);           
            smoothValue             = 0.66;
            SurfSmoothIterations    = 10;
            Vertices                = tess_smooth(Cortex.Vertices, smoothValue, SurfSmoothIterations, Cortex.VertConn, 1);    
            
            uilabel(app.PanelViewer,"Text","Face Color","Position",[25 5 60 30]);
            uicolorpicker(app.PanelViewer, "Value",[255/255 204/255 204/255],"Position", [65 5 60 30],Tooltip='Select the Face color', ...
                ValueChangedFcn=@(src,event) app.ChangeEdgeFaceColor(event,'Face'));

            uilabel(app.PanelViewer,"Text","Edge Color","Position",[25 40 60 30]);
            uicolorpicker(app.PanelViewer, "Value",[255/255 204/255 204/255],"Position", [65 40 60 30],Tooltip='Select the Edge color', ...
                ValueChangedFcn=@(src,event) app.ChangeEdgeFaceColor(event,'Edge'));

            controls = app.PanelControls.Children;            
            for i=1:length(controls)
                control = controls(i);
                if(isequal('matlab.ui.control.CheckBox',class(control)) && isequal(control.Text,'Show scouts'))
                    controlScouts = control;
                end
                if(isequal('matlab.ui.control.CheckBox',class(control)) && isequal(control.Text,'Show labels'))
                    controlLabels = control;
                end
            end

            patch(app.currentAxes, ...
                'Faces',Cortex.Faces, ...
                'Vertices',Vertices, ...
                'FaceVertexCData',[], ...
                'FaceColor',[255/255 204/255 204/255], ...
                'EdgeColor',[255/255 204/255 204/255], ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        .5, ...
                'BackfaceLighting', 'lit', ...
                'Tag',              'Cortex', ...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'FaceAlpha',.5);
            if(controlScouts.Value)
                sAtlas = Cortex.Atlas(Cortex.iAtlas);
                sScouts = sAtlas.Scouts;
                Vertices = Cortex.Vertices;
                Faces = Cortex.Faces;
                % Process each scout
                for i = 1:length(sScouts)
                    scoutColor = sScouts(i).Color;
                    % Get visible scouts vertices
                    iScoutVert = sScouts(i).Vertices;
                    patchVert = Vertices(iScoutVert,:);
                    vertMask = false(length(Vertices),1);
                    vertMask(iScoutVert) = true;
                    iFacesTmp = find(vertMask(Faces(:,1)));
                    iFacesTmp = iFacesTmp(vertMask(Faces(iFacesTmp,2)));
                    iFacesTmp = iFacesTmp(vertMask(Faces(iFacesTmp,3)));
                    patchFaces = Faces(iFacesTmp,:);
                    [~, patchFaces] = ismember(patchFaces, iScoutVert);
                    EdgeColor = 'none';
                    patchAlpha = 0.2;
                    patch(app.currentAxes,...
                        'Faces',            patchFaces, ...
                        'Vertices',         patchVert, ...
                        'FaceVertexCData',  scoutColor, ...
                        'FaceColor',        scoutColor, ...
                        'EdgeColor',        EdgeColor,...
                        'FaceAlpha',        1 - patchAlpha, ...
                        'BackFaceLighting', 'lit', ...
                        'Tag',              'ScoutPatch', ...
                        'AmbientStrength',  0.5, ...
                        'DiffuseStrength',  0.5, ...
                        'SpecularStrength', 0.2, ...
                        'SpecularExponent', 1, ...
                        'SpecularColorReflectance', 0.5, ...
                        'FaceLighting',     'gouraud', ...
                        'EdgeLighting',     'gouraud');
                end
            else
                delete(findobj(app.currentAxes, 'type', 'Patch', 'tag', 'ScoutPatch'));
            end

            % Proting Seeds
            for i=1:length(Scouts)
                line(app.currentAxes,Vertices(Scouts(i).Seed,1),Vertices(Scouts(i).Seed,2),Vertices(Scouts(i).Seed,3),...
                    'LineStyle', '-', 'Marker', 'o',  'MarkerFaceColor', Scouts(i).Color, 'MarkerSize', 10);
                if(controlLabels.Value)
                    text(app.currentAxes,Vertices(Scouts(i).Seed,1),Vertices(Scouts(i).Seed,2),Vertices(Scouts(i).Seed,3),Scouts(i).Label,'Color', Scouts(i).Color);
                end
                hold on;
            end

            % Transforming source connectivity to roi connectivity
            [ThetaSS] = source_to_roi_map(Thetajj,Scouts,length(Cortex.Vertices));
            ThetaSS = ThetaSS/max(ThetaSS(:));
            minvalue = min(ThetaSS(:));
            maxvalue = max(ThetaSS(:));
            clim([minvalue maxvalue]);

            % plotting lines
            scoutsCMap = myCmap('r',length(Scouts),100/length(Scouts));
            for i=1:length(ThetaSS)
                for j=i+1:length(ThetaSS)
                    LineWidth = ThetaSS(i,j);
                    LineWidth = abs(LineWidth) + 0.5;
                    uSeed = Vertices(Scouts(i).Seed,:);
                    vSeed = Vertices(Scouts(j).Seed,:);
                    % Ploting Seeds
                    line(app.currentAxes,uSeed(1),uSeed(2),uSeed(3), 'LineStyle', '-', 'Marker', 'o',  'MarkerFaceColor', Scouts(i).Color, 'MarkerSize', 10);
                    hold on;
                    line(app.currentAxes,vSeed(1),vSeed(2),vSeed(3), 'LineStyle', '-', 'Marker', 'o',  'MarkerFaceColor', Scouts(j).Color, 'MarkerSize', 10);
                    hold on;
                    % Getting arrow distance
                    Center = [(uSeed(1) + vSeed(1))/2 (uSeed(2) + vSeed(2))/2 (uSeed(3) + vSeed(3))/2];
                    Line = [uSeed;Center;vSeed];
                    color = interp1(linspace(min(ThetaSS(:)),max(ThetaSS(:)),length(scoutsCMap)),scoutsCMap,ThetaSS(i,j));
                    line(app.currentAxes,Line(:,1),Line(:,2),Line(:,3),'LineWidth', LineWidth, 'Color', color);
                    hold on;
                end
            end
            set(app.currentAxes,'xcolor','w','ycolor','w','zcolor','w');
            view(app.currentAxes,0,0);
            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on');
        end

        function SourceConnnectivityCircular(app,nodeData)
            clf(app.PanelViewer);
            delete(app.PanelViewer.Children);
            delete(app.currentAxes);  
            template                = load('axes.mat');
            app.currentAxes         = template.axes;            
            connectivityData          = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                nodeData.Ref_path, ...
                nodeData.Name),'Thetajj');
            Thetajj = abs(connectivityData.Thetajj(:,:));

            Cortex                  = load(fullfile(app.currentDS.Path, ...
                app.currentPt.SubID, ...
                app.currentPt.common(4).Ref_path, ...
                app.currentPt.common(4).Name));

            Scouts = Cortex.Atlas(Cortex.iAtlas).Scouts;

            [ThetaSS] = source_to_roi_map(Thetajj,Scouts,length(Cortex.Vertices));
            ThetaSS = ThetaSS/max(ThetaSS(:));
            minvalue = min(ThetaSS(:));
            maxvalue = max(ThetaSS(:));    


            %'Colormap',myColorMap,'Label',myLabel
            % scoutsCMap = myCmap('r',length(Scouts),100/length(Scouts));
            scoutsCMap = reshape([Scouts.Color],length(Scouts),length(Scouts(1).Color));
            % Plotting CircularGraph
            fig = figure("Visible","off");
            circGraph = circularGraph(ThetaSS,'Colormap',scoutsCMap,'Label',{Scouts.Label});
            copyobj(circGraph.ShowButton,app.PanelViewer);
            copyobj(circGraph.HideButton,app.PanelViewer);            
            set(gca,"Parent",app.PanelViewer);             
            app.PanelViewer.Children(1).Callback = @circularGraph.hideNodes;
            app.PanelViewer.Children(2).Callback = @circularGraph.showNodes;
            delete(fig);
            colorbar(app.PanelViewer.Children(3),'westoutside');            
            colormap(app.PanelViewer.Children(4),scoutsCMap);  
            clim([minvalue maxvalue]);
            axis(app.PanelViewer.Children(4),'tickaligned');
            app.PanelViewer.Children(3).Position(1) =  0.1;
            app.PanelViewer.Children(4).Position(2) = 0.06; 
        end
        
        function [Cortex,iHideVert]  = split_hemisphere(app,Cortex)
            [rH, lH, isConnected, iStruct, iRightScout, iLeftScout] = tess_hemisplit(Cortex);
            controls = app.PanelControls.Children;            
            for i=1:length(controls)
                control = controls(i);
                if(isequal('matlab.ui.control.CheckBox',class(control)) && isequal(control.Text,'Left Hemisphere'))
                    hemi(1) = control;
                end
                if(isequal('matlab.ui.control.CheckBox',class(control)) && isequal(control.Text,'Right Hemisphere'))
                    hemi(2) = control;
                end
                if(isequal('matlab.ui.control.CheckBox',class(control)) && isequal(control.Text,'Show Scouts'))
                    hemi(3) = control;
                end
            end
            if(~hemi(1).Value)
                iHideVert = lH;
            end
            if( ~hemi(2).Value)
                iHideVert = rH;
            end
            if(hemi(1).Value && hemi(2).Value || ...
                    ~hemi(1).Value && ~hemi(2).Value)
                iHideVert = [];
                hemi(1).Value = 1;
                hemi(2).Value = 1;
            end
            Cortex.Vertices(iHideVert,:)    = [];
            Cortex.VertConn(iHideVert,:)    = [];
            Cortex.VertConn(:,iHideVert)    = [];
            Cortex.SulciMap(iHideVert,:)    = [];
            Cortex.VertNormals(iHideVert,:) = [];
            Cortex.Curvature(iHideVert,:)   = [];
            isHideFaces                     = any(ismember(Cortex.Faces, iHideVert), 2);
            Cortex.Faces(isHideFaces,:)     = [];
            if(~hemi(1).Value)
                Cortex.Faces                = Cortex.Faces(:,:) - length(iHideVert);
            end

            if(isequal(length(hemi),3) && ~isempty(hemi(3)) && hemi(3).Value)
                sAtlas = Cortex.Atlas(Cortex.iAtlas);
                if isempty(sAtlas)
                    return;
                end
                sScouts = sAtlas.Scouts;
                if isempty(sScouts)
                    return;
                end
                sScouts(ismember({sScouts.Label},{'Thalamus L','Thalamus R','Unknown L','Unknown R','VentralDC L L','VentralDC L R','VentralDC R',...
                    'Ventricle inf-lat L','Ventricle inf-lat R','Ventricle lat L L','Ventricle lat L R','Ventricle lat R',...
                    'White L','White R'})) = [];
                if(~hemi(1).Value)
                    sScouts(ismember({sScouts.Region},{'LU','UU'})) = []; 
                end
                if( ~hemi(2).Value)
                    sScouts(ismember({sScouts.Region},{'RU','UU'})) = [];
                end  
                for i=1:length(sScouts)
                    sScouts(i).Vertices(ismember(sScouts(i).Vertices,iHideVert)) = [];
                    if(~hemi(1).Value)
                        sScouts(i).Vertices = sScouts(i).Vertices - length(iHideVert);                        
                    end
                end
                Cortex.Atlas(Cortex.iAtlas).Scouts = sScouts;
            end
        end       
        
        function ChangeEdgeFaceColor(app,event,comp)
            axesObj = findobj(app.PanelViewer.Children, 'type', 'Axes');
            patchObj = findobj(axesObj.Children, 'type', 'Patch');
            if(isequal(comp,'Face'))
                patchObj.FaceColor = event.Value;
            else
                patchObj.EdgeColor = event.Value;
            end                            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, Datasets)
            addpath(genpath("external/fieldtrip"));
            app.colorMap = load('tools/mycolormap_brain_basic_conn.mat');            
            app.Datasets_file = fullfile(callerApp.BCVdir,"Datasets.json");
            app.Datasets = Datasets;
            app.FillDatasetsTree();             
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: TreeDatasets
        function TreeDatasetsSelectionChanged(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Waiting for visualization');
            delete(app.currentAxes);            
            selectedNode = app.TreeDatasets.SelectedNodes;
            delete(app.PanelViewer.Children);
            delete(app.PanelControls.Children);

            if(isequal('matlab.ui.container.Tree',class(selectedNode.Parent)))
                datasetNode = selectedNode;
            else
                datasetNode = selectedNode.Parent;
            end
            app.currentDS = app.Datasets(find(contains({app.Datasets.UUID},datasetNode.NodeData.UUID),1));
            app.LabelDSName.Text = strcat("Name: ", app.currentDS.Name);
            app.TextAreaDSDescription.Value = app.currentDS.Description;
            participant = app.currentDS.Participants(find( ...
                contains( ...
                {app.currentDS.Participants.SubID},selectedNode.Text),1));

            %%
            %%  Filling actions
            %%
            delete(app.TreeActions.Children);
            if(isfile(fullfile(app.currentDS.Path,participant.SubID,participant.FileInfo)))
                app.currentPt = jsondecode(fileread(fullfile(app.currentDS.Path,participant.SubID,participant.FileInfo)));

                %% Common actions
                child = uitreenode(app.TreeActions,'Text','Commons');
                child.Icon = 'guide/images/common.png';
                for i=1:length(app.currentPt.common)
                    common = app.currentPt.common(i);                    
                    if(isequal(common.Comment,'Surfaces Cortex'))
                        subChild = uitreenode(child,'Text',common.Comment);
                        subChild.Icon = 'guide/images/cortex.png';
                        subChild.NodeData = common;
                    end                    
                    if(isequal(common.Comment,'Channels data'))
                        subChild = uitreenode(child,'Text',common.Comment);
                        subChild.Icon = 'guide/images/channels.png';
                        subChild.NodeData = common;
                    end
                    if(isequal(common.Comment,'Surfaces Scalp'))
                        subChild = uitreenode(child,'Text','BEM Surface');
                        subChild.Icon = 'guide/images/bemsurface.png';
                        subChild.NodeData = common;
                    end                    
                    if(isequal(common.Comment,'Headmodel'))
                        subChild = uitreenode(child,'Text','Headmodel');
                        subChild.Icon = 'guide/images/headmodel.jpeg';
                        subChild.NodeData = common;
                    end                    
                end                

                %% General actions
                child = uitreenode(app.TreeActions,'Text','General');
                child.Icon = 'guide/images/generals.png';
                generals = app.currentPt.generals;
                for i=1:length(app.currentPt.generals)
                    general = app.currentPt.generals(i);
                    if(isequal(general.Comment,'MEEG data'))
                        subChild = uitreenode(child,'Text',general.Comment);
                        subChild.Icon = 'guide/images/eeg_data.png';
                        subChild.NodeData = general;
                    end
                    if(isequal(general.Comment,'PSD'))
                        subChild = uitreenode(child,'Text','PSD');
                        subChild.Icon = 'guide/images/frequency.png';
                        subChild.NodeData = general;
                    end                    
                end

                %% Sensor level actions
                child = uitreenode(app.TreeActions,'Text','Sensor level');
                child.Icon = 'guide/images/sensor.png';
                for i=1:length(app.currentPt.sensor_level)
                    sensor = app.currentPt.sensor_level(i);
                    subChild = uitreenode(child,'Text',sensor.Freq);
                    subChild.Icon = 'guide/images/frequency.png';
                    subChild.NodeData = sensor;
                end

                %% Activation level actions
                if(isfield(app.currentPt,'activation_level') && app.currentPt.Processes(2).completed)
                    activChild = uitreenode(app.TreeActions,'Text','Activation level');
                    activChild.Icon = 'guide/images/activation.png';

                    psdChild = uitreenode(activChild,'Text','Source PSD');
                    psdChild.Icon = 'guide/images/frequency.png';
                    activPSD.Comment = "Source PSD";
                    psdChild.NodeData = activPSD;
                    % subChild.NodeData = general;

                    for i=1:length(app.currentPt.activation_level)
                        activation = app.currentPt.activation_level(i);
                        if(isequal(activation.Method,lower('sssblpp')) && ~exist('sssblChild','var'))
                            sssblChild = uitreenode(activChild,'Text','ssSBL++');
                            sssblChild.Icon = 'guide/images/methods.png';
                        end
                        if(isequal(activation.Method,lower('eloreta')) && ~exist('eloretaChild','var'))
                            eloretaChild = uitreenode(activChild,'Text','eLORETA');
                            eloretaChild.Icon = 'guide/images/methods.png';
                        end
                        if(isequal(activation.Method,lower('lcmv')) && ~exist('lcmvChild','var'))
                            lcmvChild = uitreenode(activChild,'Text','LCMV');
                            lcmvChild.Icon = 'guide/images/methods.png';
                        end
                    end
                    for i=1:length(app.currentPt.activation_level)
                        activation = app.currentPt.activation_level(i);
                        if(isequal(activation.Method,lower('sssblpp')))
                            subChild = uitreenode(sssblChild,'Text',activation.Freq);
                            subChild.Icon = 'guide/images/frequency.png';
                            subChild.NodeData = activation;
                        end
                        if(isequal(activation.Method,lower('eloreta')))
                            subChild = uitreenode(eloretaChild,'Text',activation.Freq);
                            subChild.Icon = 'guide/images/frequency.png';
                            subChild.NodeData = activation;
                        end
                        if(isequal(activation.Method,lower('lcmv')))
                            subChild = uitreenode(lcmvChild,'Text',activation.Freq);
                            subChild.Icon = 'guide/images/frequency.png';
                            subChild.NodeData = activation;
                        end
                    end
                end

                %% Connectivity actions
                if(isfield(app.currentPt,'connectivity_level') && app.currentPt.Processes(3).completed)
                    connChild = uitreenode(app.TreeActions,'Text','Connectivity level');
                    connChild.Icon = 'guide/images/connectivity.png';
                    for i=1:length(app.currentPt.connectivity_level)
                        connectivity = app.currentPt.connectivity_level(i);
                        if(isequal(connectivity.Method,lower('higgs')) && ~exist('higgsChild','var'))
                            higgsChild = uitreenode(connChild,'Text','HIGGS');
                            higgsChild.Icon = 'guide/images/methods.png';
                        end
                        if(isequal(connectivity.Method,lower('hglasso')) && ~exist('hglassoChild','var'))
                            hglassoChild = uitreenode(connChild,'Text','hgLASSO');
                            hglassoChild.Icon = 'guide/images/methods.png';
                        end                        
                    end                    
                    for i=1:length(app.currentPt.connectivity_level)
                        connectivity = app.currentPt.connectivity_level(i);
                        if(isequal(connectivity.Method,lower('higgs')))
                            subChild = uitreenode(higgsChild,'Text',connectivity.Freq);
                            subChild.Icon = 'guide/images/frequency.png';
                            subChild.NodeData = connectivity;
                        end
                        if(isequal(connectivity.Method,lower('hglasso')))
                            subChild = uitreenode(hglassoChild,'Text',connectivity.Freq);
                            subChild.Icon = 'guide/images/frequency.png';
                            subChild.NodeData = connectivity;
                        end                        
                    end
                end
                expand(app.TreeActions,'all');
                
                %% Shows cross spectrum
                app.SensorCrossSpectrum();   
                close(d)
                figure(app.UIFigure);
            end

        end

        % Selection changed function: TreeActions
        function TreeActionsSelectionChanged(app, event)
             % d = uiprogressdlg(app.UIFigure,'Title','Waiting for visualization');   
            selectedNode = app.TreeActions.SelectedNodes;
            nodeData = selectedNode.NodeData;
            clf(app.PanelViewer);
            clf(app.PanelControls);
            % Checking node level
            if(isempty(nodeData))
               expand(selectedNode);
               return;
            end
            % Preparing axes                        
            delete(app.currentAxes);            
            delete(app.PanelControls.Children);
            delete(app.PanelViewer.Children);
            % Showing Cortex and Scouts
            if(isequal(nodeData.Comment,'Surfaces Cortex'))
                Cortex = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    nodeData.Ref_path, ...
                    nodeData.Name));                
                pos = app.PanelControls.Position;
                uilabel(app.PanelControls,"Text","Color","Position",[25 5 60 30]);
                uicolorpicker(app.PanelControls, "Value",[255/255 204/255 204/255],"Position", [65 5 60 30],Tooltip='Select the color', ...
                    ValueChangedFcn=@(cbx,event) app.SCortex(Cortex));
                uicheckbox(app.PanelControls,'Text','Left Hemisphere','Value', 1,'Position',[(pos(3)/2-180) 5 120 30],...
                    'ValueChangedFcn',@(cbx,event) app.SCortex(Cortex));
                uicheckbox(app.PanelControls,'Text','Show Scouts','Value', 0,'Position',[(pos(3)/2-50) 5 100 30],...
                    'ValueChangedFcn',@(cbx,event) app.SCortex(Cortex));
                uicheckbox(app.PanelControls,'Text','Right Hemisphere','Value', 1,'Position',[(pos(3)/2+70) 5 120 30],...
                    'ValueChangedFcn',@(cbx,event) app.SCortex(Cortex));
                app.SCortex(Cortex);
            end
            % Showing EEG data
            if(isequal(nodeData.Comment,'MEEG data'))
                MEEG = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    nodeData.Ref_path, ...
                    nodeData.Name));
                if(~isempty(MEEG.data))
                    pos = app.PanelControls.Position;
                    uibutton(app.PanelControls,'Text','<<','Position',[(pos(3)/2-70) 5 30 30],...
                        'ButtonPushedFcn',@(cbt,event) app.ChangeTimeMEEGData(MEEG,1));             % << option
                    uibutton(app.PanelControls,'Text','<','Position',[(pos(3)/2-40+2) 5 30 30],...
                        'ButtonPushedFcn',@(cbt,event) app.ChangeTimeMEEGData(MEEG,2));             % < option
                    uieditfield(app.PanelControls,'Value','0','Position',[(pos(3)/2-5) 5 50 30], ...
                        'FontSize',16,'FontWeight','bold','HorizontalAlignment','center');
                    uibutton(app.PanelControls,'Text','>','Position',[(pos(3)/2+50) 5 30 30],...
                        'ButtonPushedFcn',@(cbt,event) app.ChangeTimeMEEGData(MEEG,3));             % > option
                    uibutton(app.PanelControls,'Text','>>','Position',[(pos(3)/2+80+2) 5 30 30],...
                        'ButtonPushedFcn',@(cbt,event) app.ChangeTimeMEEGData(MEEG,4));             % >> option
                    app.SMEEGData(MEEG,0);
                end
            end
            % Showing Channels data
            if(isequal(nodeData.Comment,'Channels data'))
                Cdata = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    nodeData.Ref_path, ...
                    nodeData.Name));
                Shead       = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    app.currentPt.common(1).Ref_path, ...
                    app.currentPt.common(1).Name));
                app.SChannel(Shead,Cdata);                
            end
            % Showing BEM Surfaces
            if(isequal(nodeData.Comment,'Surfaces Scalp'))
                Shead = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    nodeData.Ref_path, ...
                    nodeData.Name));
                Souter       = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    app.currentPt.common(2).Ref_path, ...
                    app.currentPt.common(2).Name));
                Sinner       = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    app.currentPt.common(3).Ref_path, ...
                    app.currentPt.common(3).Name));
                Cortex       = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    app.currentPt.common(4).Ref_path, ...
                    app.currentPt.common(4).Name));
                pos = app.PanelControls.Position;
                uicheckbox(app.PanelControls,'Text','Scalp','Value', 1,'Position',[(pos(3)/2-140) 5 60 30],...
                    'ValueChangedFcn',@(cbx,event) app.SBEMSurface(Shead,Souter,Sinner,Cortex));
                uicheckbox(app.PanelControls,'Text','Outerskull','Value', 1,'Position',[(pos(3)/2-80) 5 75 30],...
                    'ValueChangedFcn',@(cbx,event) app.SBEMSurface(Shead,Souter,Sinner,Cortex));
                uicheckbox(app.PanelControls,'Text','Innerskull','Value', 1,'Position',[(pos(3)/2) 5 75 30],...
                    'ValueChangedFcn',@(cbx,event) app.SBEMSurface(Shead,Souter,Sinner,Cortex));
                uicheckbox(app.PanelControls,'Text','Cortex','Value', 1,'Position',[(pos(3)/2+75) 5 70 30],...
                    'ValueChangedFcn',@(cbx,event) app.SBEMSurface(Shead,Souter,Sinner,Cortex));
                app.SBEMSurface(Shead,Souter,Sinner,Cortex);
            end
            % Showing Headmodel
            if(isequal(nodeData.Comment,'Headmodel'))
                Leadfield = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    nodeData.Ref_path, ...
                    nodeData.Name));
                Cdata       = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    app.currentPt.common(5).Ref_path, ...
                    app.currentPt.common(5).Name));
                Shead       = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    app.currentPt.common(1).Ref_path, ...
                    app.currentPt.common(1).Name));
                Cortex       = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    app.currentPt.common(4).Ref_path, ...
                    app.currentPt.common(4).Name));                
                pos = app.PanelControls.Position;
                uilabel(app.PanelControls,'Text','Channels:',...
                    'Position',[(pos(3)/2-70) 5 60 30]);
                uidropdown(app.PanelControls,"Items",{Cdata.Channel.Name},...
                    'Value', 'Fz','Position',[(pos(3)/2-10) 5 70 30],...
                    "ValueChangedFcn",@(dropd,event) app.SHeadmodel(Leadfield,Cdata,Shead,Cortex)); 
                uicheckbox(app.PanelControls,'Text','Scalp','Value', 1,'Position',[(pos(3)/2+70) 5 100 30],...
                    'ValueChangedFcn',@(cbx,event) app.SHeadmodel(Leadfield,Cdata,Shead,Cortex));
                app.SHeadmodel(Leadfield,Cdata,Shead,Cortex);
            end
            % Showing Generals
            if(isequal(nodeData.Comment,'PSD'))
                %% Shows cross spectrum
                app.SensorCrossSpectrum(); 
            end

            % Showing Sensors level outputs
            if(isequal(nodeData.Comment,'Sensor_level'))
                pos = app.PanelControls.Position;
                sensorData = load(fullfile(app.currentDS.Path,app.currentPt.SubID,nodeData.Ref_path,nodeData.Name));
                app.SensorTopography(sensorData);
                uibutton(app.PanelControls,'Text','Topography','Position',[(pos(3)/2-115) 5 75 30],...
                    'ButtonPushedFcn',@(cbt,event) app.SensorTopography(sensorData));
                uibutton(app.PanelControls,'Text','Sensor 3D','Position',[(pos(3)/2-35) 5 70 30],...
                    'ButtonPushedFcn',@(cbt,event) app.SensorTopography3D(sensorData));
                uibutton(app.PanelControls,'Text','ICM','Position',[(pos(3)/2+40) 5 70 30], ...
                    'Tooltip','Inverse Covariance Matrix',...
                    'ButtonPushedFcn',@(cbt,event) app.SICM(sensorData));
            end

            % Showing Source cross spectrum
            if(isequal(nodeData.Comment,'Source PSD'))
                %% Showing Source cross spectrum
                app.SourceCrossSpectrum(); 
            end

            % Showing Activation level outputs
            if(isequal(nodeData.Comment,'Activation_level'))
                pos = app.PanelControls.Position;                
                uicheckbox(app.PanelControls,'Text','Left Hemisphere','Value', 1,'Position',[(pos(3)/2-325) 5 120 30],...
                    'ValueChangedFcn',@(cbx,event) app.SourceTopography(selectedNode,nodeData));
                uibutton(app.PanelControls,'Text','Activation','Position',[(pos(3)/2-215) 5 70 30],...
                    'ButtonPushedFcn',@(cbt,event) app.SourceTopography(selectedNode,nodeData));
                uibutton(app.PanelControls,'Text','Sparse','Position',[(pos(3)/2-145) 5 70 30],...
                    'ButtonPushedFcn',@(cbt,event) app.SourceTopographySparse(selectedNode,nodeData,true));
                uilabel(app.PanelControls,'Text','Threshold:','Position',[(pos(3)/2-70) 10 70 20]);
                activationData          = load(fullfile(app.currentDS.Path, ...
                    app.currentPt.SubID, ...
                    nodeData.Ref_path, ...
                    nodeData.Name));
                stat                    = activationData.stat;
                if(isequal(size(stat,2),1))
                    Limits              = [min(stat) max(stat)];
                else
                    Limits              = [min(min(stat)) max(max(stat))];
                end                
                uispinner(app.PanelControls,'Value',min(Limits),'Position',[(pos(3)/2) 7 70 30],'Limits',Limits,'Step',.01, ...
                    'ValueChangedFcn',@(cbt,event) app.SourceTopographySparse(selectedNode,nodeData,false));
                uibutton(app.PanelControls,'Text','Stat','Position',[(pos(3)/2+75) 5 70 30],...
                    'ButtonPushedFcn',@(cbt,event) app.SourceTopographyStat(selectedNode,nodeData));
                uibutton(app.PanelControls,'Text','Other','Position',[(pos(3)/2+145) 5 70 30], ...
                    'Tooltip','Inverse Covariance Matrix',...
                    'ButtonPushedFcn',@(cbt,event) app.SourceTopography(selectedNode,nodeData));
                uicheckbox(app.PanelControls,'Text','Right Hemisphere','Value', 1,'Position',[(pos(3)/2+225) 5 120 30],...
                    'ValueChangedFcn',@(cbx,event) app.SourceTopography(selectedNode,nodeData));
                app.SourceTopography(selectedNode,nodeData);
            end 

             % Showing Connectivity level outputs
            if(isequal(nodeData.Comment,'Connectivity_level')) 
                pos = app.PanelControls.Position;
                uicheckbox(app.PanelControls,'Text','Left Hemisphere','Value', 1,'Position',[(pos(3)/2-325) 5 120 30],...
                    'ValueChangedFcn',@(src,event) app.SourceConnnectivity(nodeData));
                uicheckbox(app.PanelControls,'Text','Show labels','Value', 1,'Position',[(pos(3)/2-200) 5 120 30],...
                    'ValueChangedFcn',@(src,event) app.SourceConnnectivity(nodeData));
                uicheckbox(app.PanelControls,'Text','Show scouts','Value', 0,'Position',[(pos(3)/2-75) 5 120 30],...
                    'ValueChangedFcn',@(src,event) app.SourceConnnectivity(nodeData));
                uibutton(app.PanelControls,'Text','Circular graph','Position',[(pos(3)/2+75) 5 120 30],...
                    'ButtonPushedFcn',@(src,event) app.SourceConnnectivityCircular(nodeData));
                uicheckbox(app.PanelControls,'Text','Right Hemisphere','Value', 1,'Position',[(pos(3)/2+225) 5 120 30],...
                    'ValueChangedFcn',@(src,event) app.SourceConnnectivity(nodeData));
                app.SourceConnnectivity(nodeData);
            end 
            % close(d);
            figure(app.UIFigure);
        end

        % Callback function: LoadDatasetPushTool
        function LoadDatasetPushToolClicked(app, event)
            homedir = char(java.lang.System.getProperty('user.home'));
            folder = uigetdir(homedir,"Select the Dataset folder");
            if(isequal(folder,0))                
                return;
            end
            dataset_file = fullfile(folder,'BC_VARETA.json'); 
            if(isfile(dataset_file)) 
                BC_VARETA_info = jsondecode(fileread(dataset_file));
                BC_VARETA_info.Path = folder;
                if(~isempty(app.Datasets) && contains(BC_VARETA_info.Name,{app.Datasets.Name}))
                    opts.Interpreter = 'tex';
                    % Include the desired Default answer
                    opts.Default = 'Add';                    
                    % Use the TeX interpreter to format the question
                    quest = {strcat("The dataset: ", BC_VARETA_info.Name, " is already loaded"), ...
                        'Would you like import this the dataset anyway?'};
                    answer = questdlg(quest,'Apply changes',...
                        'Add','Replace','Cancel',opts);
                    % Handle response
                    switch answer
                        case 'Add'
                            app.Datasets(end + 1) = BC_VARETA_info;
                            [icondata,iconcmap] = imread("guide/images/success.png");
                            msg = msgbox({'The Dataset was loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                            waitfor(msg);
                            disp("-->> Dataset loaded");
                            saveJSON(app.Datasets,app.Datasets_file);
                        case 'Replace'
                            index = find(contains(BC_VARETA_info.Name,{app.Datasets.Name}),1);
                            app.Datasets(index) = BC_VARETA_info;
                            saveJSON(app.Datasets,app.Datasets_file);
                            disp("-->> Dataset replaced");
                            [icondata,iconcmap] = imread("guide/images/success.png");
                            msg = msgbox({'The Dataset was replaced successfully!!!'},'Success',"custom",icondata,iconcmap);
                            waitfor(msg);
                        otherwise
                            return;   
                    end
                else
                    if(isempty(app.Datasets))
                        app.Datasets = BC_VARETA_info;
                    else
                        app.Datasets(end + 1) = BC_VARETA_info;
                    end
                    [icondata,iconcmap] = imread("guide/images/success.png");
                    msg = msgbox({'The Dataset was loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                    waitfor(msg);
                    disp("-->> Dataset loaded");
                    saveJSON(app.Datasets,app.Datasets_file);
                end
            else
                msg = msgbox({'The selected folder do not contains the dataset file.',...
                    ' Please select a correct folder.'},'Error',"error","modal");
            end
            d = uiprogressdlg(app.UIFigure,'Title','Updating Dataset Tree');   
            app.FillDatasetsTree();
            close(d)
            figure(app.UIFigure);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Explore Datasets';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.LoadDatasetPushTool = uipushtool(app.Toolbar);
            app.LoadDatasetPushTool.Tooltip = {'Load dataset'};
            app.LoadDatasetPushTool.ClickedCallback = createCallbackFcn(app, @LoadDatasetPushToolClicked, true);
            app.LoadDatasetPushTool.Icon = fullfile(pathToMLAPP, 'images', 'upload.png');
            app.DeleteDatasetPushTool = uipushtool(app.Toolbar);
            app.DeleteDatasetPushTool.Tooltip = {'Delete dataset'};
            app.DeleteDatasetPushTool.Icon = fullfile(pathToMLAPP, 'images', 'delete.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PanelDatasets = uipanel(app.GridLayout);
            app.PanelDatasets.Title = 'Datasets';
            app.GridLayout2 = uigridlayout(app.PanelDatasets);
            app.TreeDatasets = uitree(app.GridLayout2);
            app.TreeDatasets.SelectionChangedFcn = createCallbackFcn(app, @TreeDatasetsSelectionChanged, true);
            app.Node = uitreenode(app.TreeDatasets);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Icon = fullfile(pathToMLAPP, 'images', 'participant.png');
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.Node_2 = uitreenode(app.TreeDatasets);
            app.Node_2.Text = 'Node';
            app.Node2_2 = uitreenode(app.Node_2);
            app.Node2_2.Text = 'Node2';
            app.Node3_2 = uitreenode(app.Node_2);
            app.Node3_2.Text = 'Node3';
            app.Node4_2 = uitreenode(app.Node_2);
            app.Node4_2.Text = 'Node4';
            app.Panel3 = uipanel(app.GridLayout);
            app.Panel3.Title = 'Panel3';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.PanelMain = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.PanelMain);
            app.PanelControls = uipanel(app.GridLayout5);
            app.PanelViewer = uipanel(app.GridLayout5);
            app.PanelActions = uipanel(app.GridLayout);
            app.PanelActions.Title = 'Actions';
            app.GridLayout3 = uigridlayout(app.PanelActions);
            app.TreeActions = uitree(app.GridLayout3);
            app.TreeActions.SelectionChangedFcn = createCallbackFcn(app, @TreeActionsSelectionChanged, true);
            app.NodeAction1 = uitreenode(app.TreeActions);
            app.NodeAction1.Text = 'Node';
            app.Node2_3 = uitreenode(app.NodeAction1);
            app.Node2_3.Icon = fullfile(pathToMLAPP, 'images', 'participant.png');
            app.Node2_3.Text = 'Node2';
            app.Node3_3 = uitreenode(app.NodeAction1);
            app.Node3_3.Text = 'Node3';
            app.Node4_3 = uitreenode(app.NodeAction1);
            app.Node4_3.Text = 'Node4';
            app.NodeAction2 = uitreenode(app.TreeActions);
            app.NodeAction2.Text = 'Node';
            app.Node2_4 = uitreenode(app.NodeAction2);
            app.Node2_4.Text = 'Node2';
            app.Node3_4 = uitreenode(app.NodeAction2);
            app.Node3_4.Text = 'Node3';
            app.Node4_4 = uitreenode(app.NodeAction2);
            app.Node4_4.Text = 'Node4';
            app.PanelGeneralInformation = uipanel(app.GridLayout);
            app.PanelGeneralInformation.Title = 'General Information';
            app.GridLayout4 = uigridlayout(app.PanelGeneralInformation);
            app.DescriptionLabel = uilabel(app.GridLayout4);
            app.DescriptionLabel.Text = 'Description:';
            app.TextAreaDSDescription = uitextarea(app.GridLayout4);
            app.TextAreaDSDescription.Editable = 'off';
            app.LabelDSName = uilabel(app.GridLayout4);
            app.LabelDSName.Text = 'Name:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExploreDS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end