classdef Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %%
            %% Setup inputs
            %%
            EEG = load('/mnt/Store/Data/CHBM/Processing/BC-V_Structure/HeadModel_EEG_CHBM_Dataset/Cl_eyes/sub-CBM00001/meeg/meeg.mat');
            data = EEG.data;
            time = EEG.times;
            srate = EEG.srate;
            chanlocs = EEG.chanlocs;
            nbchan = EEG.nbchan;
            pnts = EEG.pnts;
            winlength	= 5; 	 % Number of seconds of EEG displayed

            %%
            %% getting spacing
            %%
            maxindex = min(1000, pnts);
            stds = std(data(:,1:maxindex),[],2);
            stds = sort(stds);
            if length(stds) > 2
                stds = mean(stds(2:end-1));
            else
                stds = mean(stds);
            end
            spacing = stds*3;
            if spacing > 10
                spacing = round(spacing);
            end
            if spacing  == 0 || isnan(spacing)
                spacing = 1; % default
            end

            set(app.UIAxes,'Ylim',[0 (nbchan+1)*spacing]);
            set(app.UIAxes,'YTick',0:spacing:nbchan*spacing);
            set(app.UIAxes,'TickLength',[.005 .005]);

            YLabels = {chanlocs.labels}';
            YLabels = strvcat(YLabels);
            YLabels = flipud(char(YLabels,' '));
            set(app.UIAxes,'YTickLabel',YLabels)

            
            lowlim = round(time*srate+1);
            highlim = round(min((time+winlength)*srate+2,pnts));
            plot(app.UIAxes,bsxfun(@plus, data(end:-1:1,lowlim:highlim), spacing*(1:nbchan)')', ...
                'color', 'b', 'clipping','on');
            set(app.UIAxes, 'Xlim',[1 winlength*srate],...
                'XTick',1:srate:winlength*srate+1);
            set(app.UIAxes, 'XTickLabel', num2str((time:1:time+winlength)'));

            app.UIAxes.XLabel.String = 'Time (seg)';
            app.UIAxes.YLabel.String = 'Labels';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end